classdef PreProcessShell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportMenu                     matlab.ui.container.Menu
        SurfacemeshobjplystlmeshMenu   matlab.ui.container.Menu
        FEMShellMiniFEMMenu            matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        FEModelMiniFEMMenu             matlab.ui.container.Menu
        VisualizationMenu              matlab.ui.container.Menu
        ShowFEMModelMenu               matlab.ui.container.Menu
        ShowNormalsMenu                matlab.ui.container.Menu
        FlipNormalsButton              matlab.ui.control.Button
        ThicknessEditField             matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel        matlab.ui.control.Label
        CellSizeEditField              matlab.ui.control.NumericEditField
        CellSizeEditFieldLabel         matlab.ui.control.Label
        DOFsEditField                  matlab.ui.control.NumericEditField
        DOFsEditFieldLabel             matlab.ui.control.Label
        VerticesEditField              matlab.ui.control.NumericEditField
        VerticesEditFieldLabel         matlab.ui.control.Label
        ElementsEditField              matlab.ui.control.NumericEditField
        ElementsEditFieldLabel         matlab.ui.control.Label
        ApplyforBoundaryConditionsPanel  matlab.ui.container.Panel
        TabGroupSelection              matlab.ui.container.TabGroup
        BoxSelectionTab                matlab.ui.container.Tab
        CornerPot2ZEditField           matlab.ui.control.NumericEditField
        CornerPot2ZEditFieldLabel      matlab.ui.control.Label
        CornerPot1ZEditField           matlab.ui.control.NumericEditField
        CornerPot1ZEditFieldLabel      matlab.ui.control.Label
        CornerPot2YEditField           matlab.ui.control.NumericEditField
        CornerPot2YEditFieldLabel      matlab.ui.control.Label
        CornerPot1YEditField           matlab.ui.control.NumericEditField
        CornerPot1YEditFieldLabel      matlab.ui.control.Label
        CornerPot2XEditField           matlab.ui.control.NumericEditField
        CornerPot2XEditFieldLabel      matlab.ui.control.Label
        CornerPot1XEditField           matlab.ui.control.NumericEditField
        CornerPot1XEditFieldLabel      matlab.ui.control.Label
        SphereSelectionTab             matlab.ui.container.Tab
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        CenterZEditField               matlab.ui.control.NumericEditField
        CenterZEditFieldLabel          matlab.ui.control.Label
        CenterYEditField               matlab.ui.control.NumericEditField
        CenterYEditFieldLabel          matlab.ui.control.Label
        CenterXEditField               matlab.ui.control.NumericEditField
        CenterXEditFieldLabel          matlab.ui.control.Label
        SelectionOptionsDropDown       matlab.ui.control.DropDown
        SelectionOptionsDropDownLabel  matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        FixingTab                      matlab.ui.container.Tab
        RxFixedCheckBox                matlab.ui.control.CheckBox
        RyFixedCheckBox                matlab.ui.control.CheckBox
        RzFixedCheckBox                matlab.ui.control.CheckBox
        XDirFixedCheckBox              matlab.ui.control.CheckBox
        YDirFixedCheckBox              matlab.ui.control.CheckBox
        ClearFixationButton            matlab.ui.control.Button
        ApplyforFixationButton         matlab.ui.control.Button
        ZDirFixedCheckBox              matlab.ui.control.CheckBox
        LoadingTab                     matlab.ui.container.Tab
        NormalPressureButton           matlab.ui.control.Button
        RzNmEditField                  matlab.ui.control.NumericEditField
        RzNmEditFieldLabel             matlab.ui.control.Label
        RyNmEditField                  matlab.ui.control.NumericEditField
        RyNmEditFieldLabel             matlab.ui.control.Label
        RxNmEditField                  matlab.ui.control.NumericEditField
        RxNmEditFieldLabel             matlab.ui.control.Label
        FzNEditField                   matlab.ui.control.NumericEditField
        FzNEditFieldLabel              matlab.ui.control.Label
        FyNEditField                   matlab.ui.control.NumericEditField
        FyNEditFieldLabel              matlab.ui.control.Label
        ClearLoadsButton               matlab.ui.control.Button
        ApplyforLoadsButton            matlab.ui.control.Button
        FxNEditField                   matlab.ui.control.NumericEditField
        FxNEditFieldLabel              matlab.ui.control.Label
        NodeSelectionButton            matlab.ui.control.Button
        NodeUnSelectionButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        %%app_LinearSystemSolver_settings % Reference to LSS settings
        %%app_Optimizer_settings % Reference to Optimizer settings
        %%app_MaterialProperties_settings % Reference to Material Property settings
    end
    
    methods (Access = public)
        

        function SetupSelectionOptions_Public(app)
            global boundingBox_;
            app.CornerPot1XEditField.Value = boundingBox_(1,1); 
            app.CornerPot1YEditField.Value = boundingBox_(1,2); 
            app.CornerPot1ZEditField.Value = boundingBox_(1,3);
            app.CornerPot2XEditField.Value = boundingBox_(2,1); 
            app.CornerPot2YEditField.Value = boundingBox_(2,2); 
            app.CornerPot2ZEditField.Value = boundingBox_(2,3);
            
            app.CenterXEditField.Value = boundingBox_(2,1);
            app.CenterYEditField.Value = boundingBox_(2,2);
            app.CenterZEditField.Value = boundingBox_(2,3);
            app.RadiusEditField.Value = max(boundingBox_(2,:)-boundingBox_(1,:))/50;
        end             

        function InitializeAppParameters(app)
            app.ElementsEditField.Value = 0;
            app.VerticesEditField.Value = 0;
            app.DOFsEditField.Value = 0;
            app.FxNEditField.Value = 0;
            app.FyNEditField.Value = 0;
            app.FzNEditField.Value = 0;
            app.XDirFixedCheckBox.Value = 1;
            app.YDirFixedCheckBox.Value = 1;
            app.ZDirFixedCheckBox.Value = 1;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            clc;
            global axHandle_;            
            addpath('./PreProcess/');
 
            %%Initialization
            Data_GlobalVariables;
            InitializeAppParameters(app);
            close all;
            figure; axHandle_ = gca; view(axHandle_,3);
            cla(axHandle_);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Menu selected function: SurfacemeshobjplystlmeshMenu
        function ImportSurfacemeshobjplystlmeshMenuSelected(app, event)
            global simMesh_;            
            global axHandle_;
            %%Reset App
            Data_GlobalVariables;
            InitializeAppParameters(app);  

            [fileName, dataPath] = uigetfile({'*.ply'; '*.obj'; '*.stl'; '*.mesh'}, 'Select a Surface Mesh File to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            [~,~,fileExtension] = fileparts(fileName);
            if ~(strcmp(fileExtension, '.obj') || strcmp(fileExtension, '.ply') || strcmp(fileExtension, '.stl') || strcmp(fileExtension, '.mesh'))
                warning('Un-supported Mesh Format!');
                return;
            end
            inputVoxelfileName = strcat(dataPath,fileName);
            IO_ImportSurfaceMesh(inputVoxelfileName);
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            cla(axHandle_);
            ShowFEMModelMenuSelected(app, event);
            app.ElementsEditField.Value = simMesh_.numElements;
            app.VerticesEditField.Value = simMesh_.numNodes;
            app.DOFsEditField.Value = 3*simMesh_.numNodes;
            app.CellSizeEditField.Value = simMesh_.refSize;
            SetupSelectionOptions_Public(app);
        end

        % Menu selected function: FEModelMiniFEMMenu
        function ExportDesigninVolumeniiMenuSelected(app, event)
            [fileName, dataPath] = uiputfile('*.MiniFEM', 'Select a Path to Write');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            ofileName = strcat(dataPath,fileName);
            t = app.ThicknessEditField.Value;
            if 0==t
                warning('Please Assign a Thickness for the Shell!');
                return
            end
            IO_ExportFEModelShell_MiniFEM(ofileName, t);               
        end

        % Menu selected function: ShowFEMModelMenu
        function ShowFEMModelMenuSelected(app, event)
            global axHandle_;
            global simMesh_;
            global loadingCond_;
            global fixingCond_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');
            Vis_DrawMesh3D(axHandle_, simMesh_.nodeCoords, simMesh_.eNodMat, 1);
            Vis_ShowLoadingConditionShell(axHandle_, loadingCond_);
            Vis_ShowFixingConditionShell(axHandle_, fixingCond_);
            view(axHandle_, az, el);
            axis(axHandle_, 'on'); xlabel('X'); ylabel('Y'); zlabel('Z');              
        end

        % Value changed function: CenterXEditField, CenterYEditField, 
        % ...and 9 other components
        function SelectionOptionsDropDownValueChanged(app, event)
            global hdSelectionBox_;
            value = app.SelectionOptionsDropDown.Value;            
            switch value
                case 'None'
                    if isempty(hdSelectionBox_), return; end
                    if isvalid(hdSelectionBox_)
                        set(hdSelectionBox_, 'visible', 'off');
                    end
                case 'Box'
                    cP1 = zeros(1,3); cP2 = cP1;
                    cP1(1) = app.CornerPot1XEditField.Value; cP1(2) = app.CornerPot1YEditField.Value; cP1(3) = app.CornerPot1ZEditField.Value;
                    cP2(1) = app.CornerPot2XEditField.Value; cP2(2) = app.CornerPot2YEditField.Value; cP2(3) = app.CornerPot2ZEditField.Value;
                    Vis_ShowSelectionBox(cP1, cP2);
                case 'Sphere'
                    sphereRad = abs(app.RadiusEditField.Value);
                    if 0==sphereRad, return; end                    
                    sphereCtr = zeros(1,3);
                    sphereCtr(1) = app.CenterXEditField.Value;
                    sphereCtr(2) = app.CenterYEditField.Value;
                    sphereCtr(3) = app.CenterZEditField.Value;
                    Vis_ShowSelectionSphere(sphereCtr, sphereRad);
            end  
        end

        % Button pushed function: NodeSelectionButton
        function NodeSelectionButtonPushed(app, event)
            global axHandle_;
            SelectionOptionsDropDownValueChanged(app, event);
            value = app.SelectionOptionsDropDown.Value;
            switch value
                case 'Box'
                    cP1 = zeros(1,3); cP2 = cP1;
                    cP1(1) = app.CornerPot1XEditField.Value; cP1(2) = app.CornerPot1YEditField.Value; cP1(3) = app.CornerPot1ZEditField.Value;
                    cP2(1) = app.CornerPot2XEditField.Value; cP2(2) = app.CornerPot2YEditField.Value; cP2(3) = app.CornerPot2ZEditField.Value;            
                    Interaction_PickBySelectionBoxShell(axHandle_, cP1, cP2);
                case 'Sphere'
                    sphereRad = abs(app.RadiusEditField.Value);
                    if 0==sphereRad, return; end                    
                    sphereCtr = zeros(1,3);
                    sphereCtr(1) = app.CenterXEditField.Value;
                    sphereCtr(2) = app.CenterYEditField.Value;
                    sphereCtr(3) = app.CenterZEditField.Value;
                    Interaction_PickBySelectionShpereShell(axHandle_, sphereCtr, sphereRad);                    
            end            
        end

        % Button pushed function: NodeUnSelectionButton
        function NodeUnSelectionButtonPushed(app, event)
            global axHandle_;
            SelectionOptionsDropDownValueChanged(app, event);
            value = app.SelectionOptionsDropDown.Value;
            switch value
                case 'Box'
                    cP1 = zeros(1,3); cP2 = cP1;
                    cP1(1) = app.CornerPot1XEditField.Value; cP1(2) = app.CornerPot1YEditField.Value; cP1(3) = app.CornerPot1ZEditField.Value;
                    cP2(1) = app.CornerPot2XEditField.Value; cP2(2) = app.CornerPot2YEditField.Value; cP2(3) = app.CornerPot2ZEditField.Value;            
                    Interaction_UnPickBySelectionBoxShell(axHandle_, cP1, cP2);   
                case 'Sphere'
                    sphereRad = abs(app.RadiusEditField.Value);
                    if 0==sphereRad, return; end
                    sphereCtr = zeros(1,3);
                    sphereCtr(1) = app.CenterXEditField.Value;
                    sphereCtr(2) = app.CenterYEditField.Value;
                    sphereCtr(3) = app.CenterZEditField.Value;
                    Interaction_UnPickBySelectionShpereShell(axHandle_, sphereCtr, sphereRad);  
            end               
        end

        % Button pushed function: ApplyforFixationButton
        function ApplyforFixationButtonPushed(app, event)
            global axHandle_;         
            if ~(app.XDirFixedCheckBox.Value || app.YDirFixedCheckBox.Value || app.ZDirFixedCheckBox.Value), return; end
            fixingOpt = zeros(1,6);
            if app.XDirFixedCheckBox.Value, fixingOpt(1) = 1; end
            if app.YDirFixedCheckBox.Value, fixingOpt(2) = 1; end
            if app.ZDirFixedCheckBox.Value, fixingOpt(3) = 1; end
            if app.RxFixedCheckBox.Value, fixingOpt(4) = 1; end
            if app.RyFixedCheckBox.Value, fixingOpt(5) = 1; end
            if app.RzFixedCheckBox.Value, fixingOpt(6) = 1; end
            iFixingArr2Draw = FEA_Apply4FixationsShell(fixingOpt);
            if isempty(iFixingArr2Draw), return; end
            Interaction_ClearPickedNodes();
            Vis_ShowFixingConditionShell(axHandle_, iFixingArr2Draw);            
        end

        % Button pushed function: ClearFixationButton
        function ClearFixationButtonPushed(app, event)
            global fixingCond_; fixingCond_ = [];
            ShowFEMModelMenuSelected(app, event);            
        end

        % Button pushed function: ApplyforLoadsButton
        function ApplyforLoadsButtonPushed(app, event)
            global axHandle_;          
            if 0==app.FxNEditField.Value && 0==app.FyNEditField.Value && 0==app.FzNEditField.Value ...
                    && 0==app.RxNmEditField.Value && 0==app.RyNmEditField.Value && 0==app.RzNmEditField.Value
                return; 
            end
            forceVec = [app.FxNEditField.Value app.FyNEditField.Value app.FzNEditField.Value app.RxNmEditField.Value app.RyNmEditField.Value app.RzNmEditField.Value];
            iLoadingVec2Draw = FEA_Apply4LoadsShell(forceVec);
            if isempty(iLoadingVec2Draw), return; end
            Interaction_ClearPickedNodes();
            Vis_ShowLoadingConditionShell(axHandle_, iLoadingVec2Draw);            
        end

        % Button pushed function: ClearLoadsButton
        function ClearLoadsButtonPushed(app, event)
            global loadingCond_; loadingCond_ = [];       
            ShowFEMModelMenuSelected(app, event);            
        end

        % Menu selected function: ShowNormalsMenu
        function ShowNormalsMenuSelected(app, event)
            global axHandle_;
            global simMesh_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');
            Vis_DrawMesh3D(axHandle_, simMesh_.nodeCoords, simMesh_.eNodMat, 1);
            Vis_ShowShellNormals(axHandle_);
            view(axHandle_, az, el);
            axis(axHandle_, 'on'); xlabel('X'); ylabel('Y'); zlabel('Z');             
        end

        % Button pushed function: FlipNormalsButton
        function FlipNormalsButtonPushed(app, event)
            global simMesh_;
            simMesh_.eNodMat = flip(simMesh_.eNodMat,2);
            ShowNormalsMenuSelected(app, event);
        end

        % Menu selected function: FEMShellMiniFEMMenu
        function FEMShellMiniFEMMenuSelected(app, event)
            global simMesh_;            
            global axHandle_;
            %%Reset App
            Data_GlobalVariables;
            InitializeAppParameters(app);  

            [fileName, dataPath] = uigetfile('*.MiniFEM', 'Select a Surface Mesh File to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            [~,~,fileExtension] = fileparts(fileName);
            if ~strcmp(fileExtension, '.MiniFEM')
                warning('Un-supported Model Format!');
                return;
            end
            inputVoxelfileName = strcat(dataPath,fileName);
            IO_ImportSurfaceMesh_MiniFEM(inputVoxelfileName);
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            cla(axHandle_);
            ShowFEMModelMenuSelected(app, event);
            app.ElementsEditField.Value = simMesh_.numElements;
            app.VerticesEditField.Value = simMesh_.numNodes;
            app.DOFsEditField.Value = 3*simMesh_.numNodes;
            app.CellSizeEditField.Value = simMesh_.refSize;
            SetupSelectionOptions_Public(app);            
        end

        % Button pushed function: NormalPressureButton
        function NormalPressureButtonPushed(app, event)
            global axHandle_;          
            iLoadingVec2Draw = FEA_Apply4LoadsShell_NormalPressure();
            if isempty(iLoadingVec2Draw), return; end
            Interaction_ClearPickedNodes();
            Vis_ShowLoadingConditionShell(axHandle_, iLoadingVec2Draw);             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.SurfacemeshobjplystlmeshMenu = uimenu(app.ImportMenu);
            app.SurfacemeshobjplystlmeshMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSurfacemeshobjplystlmeshMenuSelected, true);
            app.SurfacemeshobjplystlmeshMenu.Text = 'Surface mesh ("*.obj", "*.ply", "*.stl", ".mesh")';
            app.FEMShellMiniFEMMenu = uimenu(app.ImportMenu);
            app.FEMShellMiniFEMMenu.MenuSelectedFcn = createCallbackFcn(app, @FEMShellMiniFEMMenuSelected, true);
            app.FEMShellMiniFEMMenu.Text = 'FEM Shell (".MiniFEM")';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.FEModelMiniFEMMenu = uimenu(app.ExportMenu);
            app.FEModelMiniFEMMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDesigninVolumeniiMenuSelected, true);
            app.FEModelMiniFEMMenu.Text = 'FEModel (*.MiniFEM)';
            app.VisualizationMenu = uimenu(app.UIFigure);
            app.VisualizationMenu.Text = 'Visualization';
            app.ShowFEMModelMenu = uimenu(app.VisualizationMenu);
            app.ShowFEMModelMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowFEMModelMenuSelected, true);
            app.ShowFEMModelMenu.Text = 'Show FEM Model';
            app.ShowNormalsMenu = uimenu(app.VisualizationMenu);
            app.ShowNormalsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowNormalsMenuSelected, true);
            app.ShowNormalsMenu.Text = 'Show Normals';
            app.ApplyforBoundaryConditionsPanel = uipanel(app.UIFigure);
            app.ApplyforBoundaryConditionsPanel.Title = 'Apply for Boundary Conditions';
            app.NodeUnSelectionButton = uibutton(app.ApplyforBoundaryConditionsPanel, 'push');
            app.NodeUnSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @NodeUnSelectionButtonPushed, true);
            app.NodeUnSelectionButton.Text = 'Node Un-Selection';
            app.NodeSelectionButton = uibutton(app.ApplyforBoundaryConditionsPanel, 'push');
            app.NodeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @NodeSelectionButtonPushed, true);
            app.NodeSelectionButton.Text = 'Node Selection';
            app.TabGroup2 = uitabgroup(app.ApplyforBoundaryConditionsPanel);
            app.FixingTab = uitab(app.TabGroup2);
            app.FixingTab.Title = 'Fixing';
            app.ZDirFixedCheckBox = uicheckbox(app.FixingTab);
            app.ZDirFixedCheckBox.Text = 'Z-Dir Fixed';
            app.ZDirFixedCheckBox.Value = true;
            app.ApplyforFixationButton = uibutton(app.FixingTab, 'push');
            app.ApplyforFixationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyforFixationButtonPushed, true);
            app.ApplyforFixationButton.Text = 'Apply for Fixation';
            app.ClearFixationButton = uibutton(app.FixingTab, 'push');
            app.ClearFixationButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFixationButtonPushed, true);
            app.ClearFixationButton.Text = 'Clear Fixation';
            app.YDirFixedCheckBox = uicheckbox(app.FixingTab);
            app.YDirFixedCheckBox.Text = 'Y-Dir Fixed';
            app.YDirFixedCheckBox.Value = true;
            app.XDirFixedCheckBox = uicheckbox(app.FixingTab);
            app.XDirFixedCheckBox.Text = 'X-Dir Fixed';
            app.XDirFixedCheckBox.Value = true;
            app.RzFixedCheckBox = uicheckbox(app.FixingTab);
            app.RzFixedCheckBox.Text = 'Rz Fixed';
            app.RzFixedCheckBox.Value = true;
            app.RyFixedCheckBox = uicheckbox(app.FixingTab);
            app.RyFixedCheckBox.Text = 'Ry Fixed';
            app.RyFixedCheckBox.Value = true;
            app.RxFixedCheckBox = uicheckbox(app.FixingTab);
            app.RxFixedCheckBox.Text = 'Rx Fixed';
            app.RxFixedCheckBox.Value = true;
            app.LoadingTab = uitab(app.TabGroup2);
            app.LoadingTab.Title = 'Loading';
            app.FxNEditFieldLabel = uilabel(app.LoadingTab);
            app.FxNEditFieldLabel.Text = 'Fx (N)';
            app.FxNEditField = uieditfield(app.LoadingTab, 'numeric');
            app.ApplyforLoadsButton = uibutton(app.LoadingTab, 'push');
            app.ApplyforLoadsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyforLoadsButtonPushed, true);
            app.ApplyforLoadsButton.Text = 'Apply for Loads';
            app.ClearLoadsButton = uibutton(app.LoadingTab, 'push');
            app.ClearLoadsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLoadsButtonPushed, true);
            app.ClearLoadsButton.Text = 'Clear Loads';
            app.FyNEditFieldLabel = uilabel(app.LoadingTab);
            app.FyNEditFieldLabel.Text = 'Fy (N)';
            app.FyNEditField = uieditfield(app.LoadingTab, 'numeric');
            app.FzNEditFieldLabel = uilabel(app.LoadingTab);
            app.FzNEditFieldLabel.Text = 'Fz (N)';
            app.FzNEditField = uieditfield(app.LoadingTab, 'numeric');
            app.RxNmEditFieldLabel = uilabel(app.LoadingTab);
            app.RxNmEditFieldLabel.Text = 'Rx (N*m)';
            app.RxNmEditField = uieditfield(app.LoadingTab, 'numeric');
            app.RyNmEditFieldLabel = uilabel(app.LoadingTab);
            app.RyNmEditFieldLabel.Text = 'Ry (N*m)';
            app.RyNmEditField = uieditfield(app.LoadingTab, 'numeric');
            app.RzNmEditFieldLabel = uilabel(app.LoadingTab);
            app.RzNmEditFieldLabel.Text = 'Rz (N*m)';
            app.RzNmEditField = uieditfield(app.LoadingTab, 'numeric');
            app.NormalPressureButton = uibutton(app.LoadingTab, 'push');
            app.NormalPressureButton.ButtonPushedFcn = createCallbackFcn(app, @NormalPressureButtonPushed, true);
            app.NormalPressureButton.Text = 'Normal Pressure';
            app.SelectionOptionsDropDownLabel = uilabel(app.ApplyforBoundaryConditionsPanel);
            app.SelectionOptionsDropDownLabel.Text = 'Selection Options';
            app.SelectionOptionsDropDown = uidropdown(app.ApplyforBoundaryConditionsPanel);
            app.SelectionOptionsDropDown.Items = {'None', 'Box', 'Sphere'};
            app.SelectionOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.SelectionOptionsDropDown.Value = 'None';
            app.TabGroupSelection = uitabgroup(app.ApplyforBoundaryConditionsPanel);
            app.BoxSelectionTab = uitab(app.TabGroupSelection);
            app.BoxSelectionTab.Title = 'Box Selection';
            app.CornerPot1XEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot1XEditFieldLabel.Text = 'Corner Pot 1 (*): X';
            app.CornerPot1XEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot1XEditField.ValueDisplayFormat = '%.6f';
            app.CornerPot1XEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot2XEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot2XEditFieldLabel.Text = 'Corner Pot 2 (+): X';
            app.CornerPot2XEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot2XEditField.ValueDisplayFormat = '%.6f';
            app.CornerPot2XEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot1YEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot1YEditFieldLabel.Text = 'Corner Pot 1 (*): Y';
            app.CornerPot1YEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot1YEditField.ValueDisplayFormat = '%.6f';
            app.CornerPot1YEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot2YEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot2YEditFieldLabel.Text = 'Corner Pot 2 (+): Y';
            app.CornerPot2YEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot2YEditField.ValueDisplayFormat = '%.6f';
            app.CornerPot2YEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot1ZEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot1ZEditFieldLabel.Text = 'Corner Pot 1 (*): Z';
            app.CornerPot1ZEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot1ZEditField.ValueDisplayFormat = '%.6f';
            app.CornerPot1ZEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot2ZEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot2ZEditFieldLabel.Text = 'Corner Pot 2 (+): Z';
            app.CornerPot2ZEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot2ZEditField.ValueDisplayFormat = '%.6f';
            app.CornerPot2ZEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.SphereSelectionTab = uitab(app.TabGroupSelection);
            app.SphereSelectionTab.Title = 'Sphere Selection';
            app.CenterXEditFieldLabel = uilabel(app.SphereSelectionTab);
            app.CenterXEditFieldLabel.Text = 'Center X';
            app.CenterXEditField = uieditfield(app.SphereSelectionTab, 'numeric');
            app.CenterXEditField.ValueDisplayFormat = '%.6f';
            app.CenterXEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CenterYEditFieldLabel = uilabel(app.SphereSelectionTab);
            app.CenterYEditFieldLabel.Text = 'Center Y';
            app.CenterYEditField = uieditfield(app.SphereSelectionTab, 'numeric');
            app.CenterYEditField.ValueDisplayFormat = '%.6f';
            app.CenterYEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CenterZEditFieldLabel = uilabel(app.SphereSelectionTab);
            app.CenterZEditFieldLabel.Text = 'Center Z';
            app.CenterZEditField = uieditfield(app.SphereSelectionTab, 'numeric');
            app.CenterZEditField.ValueDisplayFormat = '%.6f';
            app.CenterZEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.RadiusEditFieldLabel = uilabel(app.SphereSelectionTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.SphereSelectionTab, 'numeric');
            app.RadiusEditField.ValueDisplayFormat = '%.6f';
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.RadiusEditField.Value = 10;
            app.ElementsEditFieldLabel = uilabel(app.UIFigure);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.ElementsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElementsEditField.ValueDisplayFormat = '%.0f';
            app.VerticesEditFieldLabel = uilabel(app.UIFigure);
            app.VerticesEditFieldLabel.Text = '#Vertices';
            app.VerticesEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticesEditField.ValueDisplayFormat = '%.0f';
            app.DOFsEditFieldLabel = uilabel(app.UIFigure);
            app.DOFsEditFieldLabel.Text = '#DOFs';
            app.DOFsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DOFsEditField.ValueDisplayFormat = '%.0f';
            app.CellSizeEditFieldLabel = uilabel(app.UIFigure);
            app.CellSizeEditFieldLabel.Text = 'Cell Size';
            app.CellSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CellSizeEditField.ValueDisplayFormat = '%.6f';
            app.ThicknessEditFieldLabel = uilabel(app.UIFigure);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThicknessEditField.ValueDisplayFormat = '%.6f';
            app.FlipNormalsButton = uibutton(app.UIFigure, 'push');
            app.FlipNormalsButton.ButtonPushedFcn = createCallbackFcn(app, @FlipNormalsButtonPushed, true);
            app.FlipNormalsButton.Text = 'Flip Normals';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreProcessShell(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end