classdef PreProcessVolumetric < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportMenu                     matlab.ui.container.Menu
        SolidmeshmshmeshvtkMenu        matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        FEModelMiniFEMMenu             matlab.ui.container.Menu
        VisualizationMenu              matlab.ui.container.Menu
        ShowFEMModelMenu               matlab.ui.container.Menu
        DOFsEditField                  matlab.ui.control.NumericEditField
        DOFsEditFieldLabel             matlab.ui.control.Label
        VerticesEditField              matlab.ui.control.NumericEditField
        VerticesEditFieldLabel         matlab.ui.control.Label
        ElementsEditField              matlab.ui.control.NumericEditField
        ElementsEditFieldLabel         matlab.ui.control.Label
        ApplyforBoundaryConditionsPanel  matlab.ui.container.Panel
        TabGroupSelection              matlab.ui.container.TabGroup
        BoxSelectionTab                matlab.ui.container.Tab
        CornerPot2ZEditField           matlab.ui.control.NumericEditField
        CornerPot2ZEditFieldLabel      matlab.ui.control.Label
        CornerPot1ZEditField           matlab.ui.control.NumericEditField
        CornerPot1ZEditFieldLabel      matlab.ui.control.Label
        CornerPot2YEditField           matlab.ui.control.NumericEditField
        CornerPot2YEditFieldLabel      matlab.ui.control.Label
        CornerPot1YEditField           matlab.ui.control.NumericEditField
        CornerPot1YEditFieldLabel      matlab.ui.control.Label
        CornerPot2XEditField           matlab.ui.control.NumericEditField
        CornerPot2XEditFieldLabel      matlab.ui.control.Label
        CornerPot1XEditField           matlab.ui.control.NumericEditField
        CornerPot1XEditFieldLabel      matlab.ui.control.Label
        SphereSelectionTab             matlab.ui.container.Tab
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        CenterZEditField               matlab.ui.control.NumericEditField
        CenterZEditFieldLabel          matlab.ui.control.Label
        CenterYEditField               matlab.ui.control.NumericEditField
        CenterYEditFieldLabel          matlab.ui.control.Label
        CenterXEditField               matlab.ui.control.NumericEditField
        CenterXEditFieldLabel          matlab.ui.control.Label
        SelectionOptionsDropDown       matlab.ui.control.DropDown
        SelectionOptionsDropDownLabel  matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        FixingTab                      matlab.ui.container.Tab
        XDirFixedCheckBox              matlab.ui.control.CheckBox
        YDirFixedCheckBox              matlab.ui.control.CheckBox
        ClearFixationButton            matlab.ui.control.Button
        ApplyforFixationButton         matlab.ui.control.Button
        ZDirFixedCheckBox              matlab.ui.control.CheckBox
        LoadingTab                     matlab.ui.container.Tab
        FzNEditField                   matlab.ui.control.NumericEditField
        FzNEditFieldLabel              matlab.ui.control.Label
        FyNEditField                   matlab.ui.control.NumericEditField
        FyNEditFieldLabel              matlab.ui.control.Label
        ClearLoadsButton               matlab.ui.control.Button
        ApplyforLoadsButton            matlab.ui.control.Button
        FxNEditField                   matlab.ui.control.NumericEditField
        FxNEditFieldLabel              matlab.ui.control.Label
        NodeSelectionButton            matlab.ui.control.Button
        NodeUnSelectionButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        %%app_LinearSystemSolver_settings % Reference to LSS settings
        %%app_Optimizer_settings % Reference to Optimizer settings
        %%app_MaterialProperties_settings % Reference to Material Property settings
    end
    
    methods (Access = public)
        

        function SetupSelectionOptions_Public(app)
            global boundingBox_;
            app.CornerPot1XEditField.Value = boundingBox_(1,1); 
            app.CornerPot1YEditField.Value = boundingBox_(1,2); 
            app.CornerPot1ZEditField.Value = boundingBox_(1,3);
            app.CornerPot2XEditField.Value = boundingBox_(2,1); 
            app.CornerPot2YEditField.Value = boundingBox_(2,2); 
            app.CornerPot2ZEditField.Value = boundingBox_(2,3);
            
            app.CenterXEditField.Value = boundingBox_(2,1);
            app.CenterYEditField.Value = boundingBox_(2,2);
            app.CenterZEditField.Value = boundingBox_(2,3);
            app.RadiusEditField.Value = max(boundingBox_(2,:)-boundingBox_(1,:))/50;
        end             

        function InitializeAppParameters(app)
            app.ElementsEditField.Value = 0;
            app.VerticesEditField.Value = 0;
            app.DOFsEditField.Value = 0;
            app.FxNEditField.Value = 0;
            app.FyNEditField.Value = 0;
            app.FzNEditField.Value = 0;
            app.XDirFixedCheckBox.Value = 1;
            app.YDirFixedCheckBox.Value = 1;
            app.ZDirFixedCheckBox.Value = 1;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            clc;
            global axHandle_;            
            addpath('./PreProcess/');
 
            %%Initialization
            Data_GlobalVariables;
            InitializeAppParameters(app);
            close all;
            figure; axHandle_ = gca; view(axHandle_,3);
            cla(axHandle_);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Menu selected function: SolidmeshmshmeshvtkMenu
        function ImportSolidmeshmshmeshvtkMenuSelected(app, event)
            global simMesh_;            
            global axHandle_;
            %%Reset App
            Data_GlobalVariables;
            InitializeAppParameters(app);  

            [fileName, dataPath] = uigetfile({'*.msh'; '*.mesh'; '*.vtk'}, 'Select a Volumetric Mesh File to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            [~,~,fileExtension] = fileparts(fileName);
            if ~(strcmp(fileExtension, '.msh') || strcmp(fileExtension, '.mesh') || strcmp(fileExtension, '.vtk'))
                warning('Un-supported Mesh Format!');
                return;
            end
            inputVoxelfileName = strcat(dataPath,fileName);
            IO_ImportVolumetricMesh(inputVoxelfileName);
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            cla(axHandle_);
            ShowFEMModelMenuSelected(app, event);
            app.ElementsEditField.Value = simMesh_.numElements;
            app.VerticesEditField.Value = simMesh_.numNodes;
            app.DOFsEditField.Value = 3*simMesh_.numNodes;
            SetupSelectionOptions_Public(app);
        end

        % Menu selected function: FEModelMiniFEMMenu
        function ExportDesigninVolumeniiMenuSelected(app, event)
            [fileName, dataPath] = uiputfile('*.MiniFEM', 'Select a Path to Write');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            ofileName = strcat(dataPath,fileName);
            IO_ExportFEModel_MiniFEM(ofileName);               
        end

        % Menu selected function: ShowFEMModelMenu
        function ShowFEMModelMenuSelected(app, event)
            global axHandle_;
            global surfMesh_;
            global loadingCond_;
            global fixingCond_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');
            Vis_DrawMesh3D(axHandle_, surfMesh_.nodeCoords, surfMesh_.eNodMat, 1);
            Vis_ShowLoadingCondition(axHandle_, loadingCond_);
            Vis_ShowFixingCondition(axHandle_, fixingCond_);
            view(axHandle_, az, el);
            axis(axHandle_, 'on'); xlabel('X'); ylabel('Y'); zlabel('Z');              
        end

        % Value changed function: CenterXEditField, CenterYEditField, 
        % ...and 9 other components
        function SelectionOptionsDropDownValueChanged(app, event)
            global hdSelectionBox_;
            value = app.SelectionOptionsDropDown.Value;            
            switch value
                case 'None'
                    if isempty(hdSelectionBox_), return; end
                    if isvalid(hdSelectionBox_)
                        set(hdSelectionBox_, 'visible', 'off');
                    end
                case 'Box'
                    cP1 = zeros(1,3); cP2 = cP1;
                    cP1(1) = app.CornerPot1XEditField.Value; cP1(2) = app.CornerPot1YEditField.Value; cP1(3) = app.CornerPot1ZEditField.Value;
                    cP2(1) = app.CornerPot2XEditField.Value; cP2(2) = app.CornerPot2YEditField.Value; cP2(3) = app.CornerPot2ZEditField.Value;
                    Vis_ShowSelectionBox(cP1, cP2);
                case 'Sphere'
                    sphereRad = abs(app.RadiusEditField.Value);
                    if 0==sphereRad, return; end                    
                    sphereCtr = zeros(1,3);
                    sphereCtr(1) = app.CenterXEditField.Value;
                    sphereCtr(2) = app.CenterYEditField.Value;
                    sphereCtr(3) = app.CenterZEditField.Value;
                    Vis_ShowSelectionSphere(sphereCtr, sphereRad);
            end  
        end

        % Button pushed function: NodeSelectionButton
        function NodeSelectionButtonPushed(app, event)
            global axHandle_;
            SelectionOptionsDropDownValueChanged(app, event);
            value = app.SelectionOptionsDropDown.Value;
            switch value
                case 'Box'
                    cP1 = zeros(1,3); cP2 = cP1;
                    cP1(1) = app.CornerPot1XEditField.Value; cP1(2) = app.CornerPot1YEditField.Value; cP1(3) = app.CornerPot1ZEditField.Value;
                    cP2(1) = app.CornerPot2XEditField.Value; cP2(2) = app.CornerPot2YEditField.Value; cP2(3) = app.CornerPot2ZEditField.Value;            
                    Interaction_PickBySelectionBox(axHandle_, cP1, cP2);
                case 'Sphere'
                    sphereRad = abs(app.RadiusEditField.Value);
                    if 0==sphereRad, return; end                    
                    sphereCtr = zeros(1,3);
                    sphereCtr(1) = app.CenterXEditField.Value;
                    sphereCtr(2) = app.CenterYEditField.Value;
                    sphereCtr(3) = app.CenterZEditField.Value;
                    Interaction_PickBySelectionShpere(axHandle_, sphereCtr, sphereRad);                    
            end            
        end

        % Button pushed function: NodeUnSelectionButton
        function NodeUnSelectionButtonPushed(app, event)
            global axHandle_;
            SelectionOptionsDropDownValueChanged(app, event);
            value = app.SelectionOptionsDropDown.Value;
            switch value
                case 'Box'
                    cP1 = zeros(1,3); cP2 = cP1;
                    cP1(1) = app.CornerPot1XEditField.Value; cP1(2) = app.CornerPot1YEditField.Value; cP1(3) = app.CornerPot1ZEditField.Value;
                    cP2(1) = app.CornerPot2XEditField.Value; cP2(2) = app.CornerPot2YEditField.Value; cP2(3) = app.CornerPot2ZEditField.Value;            
                    Interaction_UnPickBySelectionBox(axHandle_, cP1, cP2);   
                case 'Sphere'
                    sphereRad = abs(app.RadiusEditField.Value);
                    if 0==sphereRad, return; end
                    sphereCtr = zeros(1,3);
                    sphereCtr(1) = app.CenterXEditField.Value;
                    sphereCtr(2) = app.CenterYEditField.Value;
                    sphereCtr(3) = app.CenterZEditField.Value;
                    Interaction_UnPickBySelectionShpere(axHandle_, sphereCtr, sphereRad);  
            end               
        end

        % Button pushed function: ApplyforFixationButton
        function ApplyforFixationButtonPushed(app, event)
            global axHandle_;         
            if ~(app.XDirFixedCheckBox.Value || app.YDirFixedCheckBox.Value || app.ZDirFixedCheckBox.Value), return; end
            fixingOpt = zeros(1,3);
            if app.XDirFixedCheckBox.Value, fixingOpt(1) = 1; end
            if app.YDirFixedCheckBox.Value, fixingOpt(2) = 1; end
            if app.ZDirFixedCheckBox.Value, fixingOpt(3) = 1; end
            iFixingArr2Draw = FEA_Apply4Fixations(fixingOpt);
            if isempty(iFixingArr2Draw), return; end
            Interaction_ClearPickedNodes();
            Vis_ShowFixingCondition(axHandle_, iFixingArr2Draw);            
        end

        % Button pushed function: ClearFixationButton
        function ClearFixationButtonPushed(app, event)
            global fixingCond_; fixingCond_ = [];
            ShowFEMModelMenuSelected(app, event);            
        end

        % Button pushed function: ApplyforLoadsButton
        function ApplyforLoadsButtonPushed(app, event)
            global axHandle_;          
            if 0==app.FxNEditField.Value && 0==app.FyNEditField.Value && 0==app.FzNEditField.Value, return; end
            forceVec = [app.FxNEditField.Value app.FyNEditField.Value app.FzNEditField.Value];
            iLoadingVec2Draw = FEA_Apply4Loads(forceVec);
            if isempty(iLoadingVec2Draw), return; end
            Interaction_ClearPickedNodes();
            Vis_ShowLoadingCondition(axHandle_, iLoadingVec2Draw);            
        end

        % Button pushed function: ClearLoadsButton
        function ClearLoadsButtonPushed(app, event)
            global loadingCond_; loadingCond_ = [];       
            ShowFEMModelMenuSelected(app, event);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.SolidmeshmshmeshvtkMenu = uimenu(app.ImportMenu);
            app.SolidmeshmshmeshvtkMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSolidmeshmshmeshvtkMenuSelected, true);
            app.SolidmeshmshmeshvtkMenu.Text = 'Solid mesh (*.msh, *.mesh, ".vtk")';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.FEModelMiniFEMMenu = uimenu(app.ExportMenu);
            app.FEModelMiniFEMMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDesigninVolumeniiMenuSelected, true);
            app.FEModelMiniFEMMenu.Text = 'FEModel (*.MiniFEM)';
            app.VisualizationMenu = uimenu(app.UIFigure);
            app.VisualizationMenu.Text = 'Visualization';
            app.ShowFEMModelMenu = uimenu(app.VisualizationMenu);
            app.ShowFEMModelMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowFEMModelMenuSelected, true);
            app.ShowFEMModelMenu.Text = 'Show FEM Model';
            app.ApplyforBoundaryConditionsPanel = uipanel(app.UIFigure);
            app.ApplyforBoundaryConditionsPanel.Title = 'Apply for Boundary Conditions';
            app.NodeUnSelectionButton = uibutton(app.ApplyforBoundaryConditionsPanel, 'push');
            app.NodeUnSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @NodeUnSelectionButtonPushed, true);
            app.NodeUnSelectionButton.Text = 'Node Un-Selection';
            app.NodeSelectionButton = uibutton(app.ApplyforBoundaryConditionsPanel, 'push');
            app.NodeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @NodeSelectionButtonPushed, true);
            app.NodeSelectionButton.Text = 'Node Selection';
            app.TabGroup2 = uitabgroup(app.ApplyforBoundaryConditionsPanel);
            app.FixingTab = uitab(app.TabGroup2);
            app.FixingTab.Title = 'Fixing';
            app.ZDirFixedCheckBox = uicheckbox(app.FixingTab);
            app.ZDirFixedCheckBox.Text = 'Z-Dir Fixed';
            app.ZDirFixedCheckBox.Value = true;
            app.ApplyforFixationButton = uibutton(app.FixingTab, 'push');
            app.ApplyforFixationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyforFixationButtonPushed, true);
            app.ApplyforFixationButton.Text = 'Apply for Fixation';
            app.ClearFixationButton = uibutton(app.FixingTab, 'push');
            app.ClearFixationButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFixationButtonPushed, true);
            app.ClearFixationButton.Text = 'Clear Fixation';
            app.YDirFixedCheckBox = uicheckbox(app.FixingTab);
            app.YDirFixedCheckBox.Text = 'Y-Dir Fixed';
            app.YDirFixedCheckBox.Value = true;
            app.XDirFixedCheckBox = uicheckbox(app.FixingTab);
            app.XDirFixedCheckBox.Text = 'X-Dir Fixed';
            app.XDirFixedCheckBox.Value = true;
            app.LoadingTab = uitab(app.TabGroup2);
            app.LoadingTab.Title = 'Loading';
            app.FxNEditFieldLabel = uilabel(app.LoadingTab);
            app.FxNEditFieldLabel.Text = 'Fx (N)';
            app.FxNEditField = uieditfield(app.LoadingTab, 'numeric');
            app.ApplyforLoadsButton = uibutton(app.LoadingTab, 'push');
            app.ApplyforLoadsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyforLoadsButtonPushed, true);
            app.ApplyforLoadsButton.Text = 'Apply for Loads';
            app.ClearLoadsButton = uibutton(app.LoadingTab, 'push');
            app.ClearLoadsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLoadsButtonPushed, true);
            app.ClearLoadsButton.Text = 'Clear Loads';
            app.FyNEditFieldLabel = uilabel(app.LoadingTab);
            app.FyNEditFieldLabel.Text = 'Fy (N)';
            app.FyNEditField = uieditfield(app.LoadingTab, 'numeric');
            app.FzNEditFieldLabel = uilabel(app.LoadingTab);
            app.FzNEditFieldLabel.Text = 'Fz (N)';
            app.FzNEditField = uieditfield(app.LoadingTab, 'numeric');
            app.SelectionOptionsDropDownLabel = uilabel(app.ApplyforBoundaryConditionsPanel);
            app.SelectionOptionsDropDownLabel.Text = 'Selection Options';
            app.SelectionOptionsDropDown = uidropdown(app.ApplyforBoundaryConditionsPanel);
            app.SelectionOptionsDropDown.Items = {'None', 'Box', 'Sphere'};
            app.SelectionOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.SelectionOptionsDropDown.Value = 'None';
            app.TabGroupSelection = uitabgroup(app.ApplyforBoundaryConditionsPanel);
            app.BoxSelectionTab = uitab(app.TabGroupSelection);
            app.BoxSelectionTab.Title = 'Box Selection';
            app.CornerPot1XEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot1XEditFieldLabel.Text = 'Corner Pot 1 (*): X';
            app.CornerPot1XEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot1XEditField.ValueDisplayFormat = '%.1f';
            app.CornerPot1XEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot2XEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot2XEditFieldLabel.Text = 'Corner Pot 2 (+): X';
            app.CornerPot2XEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot2XEditField.ValueDisplayFormat = '%.1f';
            app.CornerPot2XEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot1YEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot1YEditFieldLabel.Text = 'Corner Pot 1 (*): Y';
            app.CornerPot1YEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot1YEditField.ValueDisplayFormat = '%.1f';
            app.CornerPot1YEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot2YEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot2YEditFieldLabel.Text = 'Corner Pot 2 (+): Y';
            app.CornerPot2YEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot2YEditField.ValueDisplayFormat = '%.1f';
            app.CornerPot2YEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot1ZEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot1ZEditFieldLabel.Text = 'Corner Pot 1 (*): Z';
            app.CornerPot1ZEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot1ZEditField.ValueDisplayFormat = '%.1f';
            app.CornerPot1ZEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CornerPot2ZEditFieldLabel = uilabel(app.BoxSelectionTab);
            app.CornerPot2ZEditFieldLabel.Text = 'Corner Pot 2 (+): Z';
            app.CornerPot2ZEditField = uieditfield(app.BoxSelectionTab, 'numeric');
            app.CornerPot2ZEditField.ValueDisplayFormat = '%.1f';
            app.CornerPot2ZEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.SphereSelectionTab = uitab(app.TabGroupSelection);
            app.SphereSelectionTab.Title = 'Sphere Selection';
            app.CenterXEditFieldLabel = uilabel(app.SphereSelectionTab);
            app.CenterXEditFieldLabel.Text = 'Center X';
            app.CenterXEditField = uieditfield(app.SphereSelectionTab, 'numeric');
            app.CenterXEditField.ValueDisplayFormat = '%.1f';
            app.CenterXEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CenterYEditFieldLabel = uilabel(app.SphereSelectionTab);
            app.CenterYEditFieldLabel.Text = 'Center Y';
            app.CenterYEditField = uieditfield(app.SphereSelectionTab, 'numeric');
            app.CenterYEditField.ValueDisplayFormat = '%.1f';
            app.CenterYEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.CenterZEditFieldLabel = uilabel(app.SphereSelectionTab);
            app.CenterZEditFieldLabel.Text = 'Center Z';
            app.CenterZEditField = uieditfield(app.SphereSelectionTab, 'numeric');
            app.CenterZEditField.ValueDisplayFormat = '%.1f';
            app.CenterZEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.RadiusEditFieldLabel = uilabel(app.SphereSelectionTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.SphereSelectionTab, 'numeric');
            app.RadiusEditField.ValueDisplayFormat = '%.1f';
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionOptionsDropDownValueChanged, true);
            app.RadiusEditField.Value = 10;
            app.ElementsEditFieldLabel = uilabel(app.UIFigure);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.ElementsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElementsEditField.ValueDisplayFormat = '%.0f';
            app.VerticesEditFieldLabel = uilabel(app.UIFigure);
            app.VerticesEditFieldLabel.Text = '#Vertices';
            app.VerticesEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticesEditField.ValueDisplayFormat = '%.0f';
            app.DOFsEditFieldLabel = uilabel(app.UIFigure);
            app.DOFsEditFieldLabel.Text = '#DOFs';
            app.DOFsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DOFsEditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreProcessVolumetric(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end